classdef ThresholdReviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Panel              matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        SaveButton         matlab.ui.control.Button
        RippleCheckBox     matlab.ui.control.CheckBox
        SharpwaveCheckBox  matlab.ui.control.CheckBox
        GridLayout8        matlab.ui.container.GridLayout
        RSpinner           matlab.ui.control.Spinner
        RThresholdLabel    matlab.ui.control.Label
        GridLayout7        matlab.ui.container.GridLayout
        SWSpinner          matlab.ui.control.Spinner
        SWThresholdLabel   matlab.ui.control.Label
        GridLayout3        matlab.ui.container.GridLayout
        EventsSlider       matlab.ui.control.Slider
        NextButton         matlab.ui.control.Button
        LastButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x;
        L;
        bel;
        pyr;
        rat;
        filt_bel;
        filt_pyr;
        fn = 600;
        detections;
        r_threshold;
        sw_threshold;
        window = 3601;
        detections_num;
        true_positive_ripples = [];
        true_positive_sharpwaves = [];
    end
    
    methods (Access = private)
        function update(app)
            half_wind = fix(app.window/2);
            % Get event number
            det_num = app.EventsSlider.Value;
            % start
            det_s = (app.detections.Peak(det_num) - half_wind);
            % end
            det_e = (app.detections.Peak(det_num) + half_wind);
            
            % Update saved or unsaved state
            if ismember(det_num, app.true_positive_ripples)
                app.RippleCheckBox.Value = 1;
            else
                app.RippleCheckBox.Value = 0;
            end
            if ismember(det_num, app.true_positive_sharpwaves)
                app.SharpwaveCheckBox.Value = 1;
            else
                app.SharpwaveCheckBox.Value = 0;
            end
            
            bel_sig = app.bel;
            space = max(bel_sig(det_s:det_e)) - min(bel_sig(det_s:det_e));
            pyr_sig = 2*app.pyr + space;
            space = max(pyr_sig(det_s:det_e)) - min(bel_sig(det_s:det_e));
            filt_bel_sig = app.filt_bel + space;
            sw_thres = mean(app.filt_bel)-app.sw_threshold*std(app.filt_bel) + space;
            space = max(filt_bel_sig(det_s:det_e)) - min(bel_sig(det_s:det_e));
            filt_pyr_sig = 12.*app.filt_pyr + space;
            r_thres = 12*(mean(app.filt_pyr)+app.r_threshold*std(app.filt_pyr)) + space;
            
            y_max = max(filt_pyr_sig(det_s:det_e)) + 250;
            y_min = min(bel_sig(det_s:det_e)) - 250;

            title(app.UIAxes, strcat("Event ", int2str(det_num)))
            cla(app.UIAxes)
            plot(app.UIAxes, app.x, filt_pyr_sig, 'Color', 'blue')
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.x, filt_bel_sig, 'Color', 'black')
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.x, pyr_sig, 'Color', 'blue')
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.x, bel_sig, 'Color', 'black')
            
            hold(app.UIAxes,'on')
            yline(app.UIAxes, r_thres)
            hold(app.UIAxes,'on')
            yline(app.UIAxes, sw_thres)
            
            app.UIAxes.YLim = [y_min y_max];
            app.UIAxes.XLim = [det_s/app.fn, det_e/app.fn];
            
            hold(app.UIAxes,'on')
            txt = app.detections.Type(det_num);
            text(app.UIAxes, det_e-1, y_max+250, txt)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            source_path = 'D:\Dev\MATLAB\GenzelLab'; %uigetdir('','Source Path');
            results_folder = fullfile(source_path, "sleep_oscil_tools", ...
                                      "results", "thresholds");
            
            prompt = {'Enter rat:','Enter batch:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            animal = answer{1};
            batch = answer{2};

            if ~exist(results_folder, 'dir')
                mkdir(results_folder)
            end
            
            % Sleep Oscilations Tools (self)
            addpath(fullfile(source_path, "sleep_oscil_tools"));
            
            % Rat N - vehicle
            data_path = fullfile(source_path, "data");
            %Paths
            sleep_states_path = fullfile(data_path, "PCA_state_files", ...
                                      "PCA_Scorer_batch_" + batch, ...
                                      "Rat" + animal, "states.mat" );
            pyra_path = fullfile(data_path, "HPC_all_animals", ...
                            "HPC_" + animal + "_CHpyr.continuous.mat");
            abov_path = fullfile(data_path, "HPC_all_animals", ...
                            "HPC_" + animal + "_CHab.continuous.mat");
            belo_path = fullfile(data_path, "HPC_all_animals", ...
                            "HPC_" + animal + "_CHbel.continuous.mat");
            shal_path = fullfile(data_path, "PFC_all_animals", ...
                            "PFC_" + animal + "_CH37.continuous.mat");
            deep_path = fullfile(data_path, "PFC_all_animals", ...
                            "PFC_" + animal + "_CH60.continuous.mat");
            
            % Sleep states across the signal
            clusters = importdata(sleep_states_path);
            sleep_states = zeros(1, length(clusters)*app.fn*10);
            for i = 1:length(clusters)
                state = repmat(clusters(i),1,6000);
                pos = 6000*i-5999;
                sleep_states(pos:pos+5999) = state;
            end
            app.L = length(sleep_states);
            
            % Load data 
            pyra = importdata(pyra_path);
            abov = importdata(abov_path);
            belo = importdata(belo_path);
            shal = importdata(shal_path);
            deep = importdata(deep_path);
            
            % Crop data to the same length
            pyra = pyra(1:app.L);
            abov = abov(1:app.L);
            belo = belo(1:app.L);
            shal = shal(1:app.L);
            deep = deep(1:app.L);
            
            % Sum of amplitudes to visually assess artifacts as they 
            % will appear in every channel and add up
            TOT = abs(pyra) + abs(abov) + abs(belo) + abs(shal)+ abs(deep);

            
            %% Artifacts
            
            prompt = "Set the artifact threshold";
            dlgtitle = "Artifact threshold";
            answer = inputdlg(prompt,dlgtitle);
            tr = str2double(answer); %Visual threshold 
            outliers = false(app.L,1);
            index = 1;
            while index<app.L
                if TOT(index)>=tr
                    if index-300 < 1
                        lo_lim = 1;
                    else 
                        lo_lim = index-300; 
                    end
                    if index+1999 > app.L 
                        hi_lim = app.L; 
                    else 
                        hi_lim = index+1999; 
                    end
                    sz = length(lo_lim:hi_lim);
                    % When we detect an artifact, 
                    % remove 300 datapoints prior 
                    % (build up of artifact) and 
                    % 2000 datapoints after (3.5 sec)
                    outliers(lo_lim:hi_lim) = ones(sz,1); 
                    index = index+2000;
                else
                    index = index +1;
                end
            end
            
            %Filter out artifacts & replace with the mean of the channels' medians
            hpc_mean = mean(median([pyra, abov, belo]));
            
            app.bel = belo;
            app.pyr = pyra;
            [c,d] = butter(3, [2/300 20/300]);
            [e,f] = butter(3, [90/300 200/300]);
            app.filt_bel = filtfilt(c,d, belo);
            app.filt_pyr = filtfilt(e,f, pyra);
            
            app.bel(outliers,:) = hpc_mean;
            app.pyr(outliers,:) = hpc_mean;
            app.filt_bel(outliers,:) = hpc_mean;
            app.filt_pyr(outliers,:) = hpc_mean;
            
            app.x = (1:app.L)/app.fn;
            
            app.sw_threshold = 5;
            app.r_threshold = 5;
            app.SWSpinner.Value = app.sw_threshold;
            app.RSpinner.Value = app.r_threshold;
            
            [file,path] = uigetfile('','Select detections');
            
            if file ~= 0
                
                app.rat = strcat('_rat_', file(11:end-4));
            
                filename = fullfile(path,file);
                
                variable = 'grouped_oscil_table';
                S = load(filename, variable);
                app.detections = S.(variable);
                
                app.detections_num = height(app.detections);
                app.EventsSlider.Limits = [1 app.detections_num];

                app.SharpwaveCheckBox.Enable = 'on';
                app.RippleCheckBox.Enable = 'on';
                app.EventsSlider.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.NextButton.Enable = 'on';
                app.LastButton.Enable = 'on';
                app.SWSpinner.Enable = 'on';
                app.RSpinner.Enable = 'on';
                
                app.update()
                
            else
                delete(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.EventsSlider.Value < app.detections_num
                app.EventsSlider.Value = app.EventsSlider.Value + 1;
                app.update()
            end
        end

        % Button pushed function: LastButton
        function LastButtonPushed(app, event)
            if app.EventsSlider.Value > 1
                app.EventsSlider.Value = app.EventsSlider.Value - 1;
                app.update()
            end
        end

        % Value changed function: EventsSlider
        function EventsSliderValueChanged(app, event)
            value = round(app.EventsSlider.Value);
            app.EventsSlider.Value = value;
            app.update()
        end

        % Value changed function: RippleCheckBox
        function RippleCheckBoxValueChanged(app, event)
            value = app.RippleCheckBox.Value;
            if value
                app.true_positive_ripples = [app.true_positive_ripples app.EventsSlider.Value];
            else
                app.true_positive_ripples(app.true_positive_ripples == app.EventsSlider.Value) = [];
            end
            app.update()
        end

        % Value changed function: SharpwaveCheckBox
        function SharpwaveCheckBoxValueChanged(app, event)
            value = app.SharpwaveCheckBox.Value;
            if value
                app.true_positive_sharpwaves = [app.true_positive_sharpwaves app.EventsSlider.Value];
            else
                app.true_positive_sharpwaves(app.true_positive_sharpwaves == app.EventsSlider.Value) = [];
            end
            app.update()
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ripples = app.true_positive_ripples;
            sharpwaves = app.true_positive_sharpwaves;
            ripples_threshold = app.r_threshold;
            sharpwaves_threshold = app.sw_threshold;
            vars = {'ripples', ...
                    'sharpwaves', ...
                    'ripples_threshold', ...
                    'sharpwaves_threshold'};
            file = strcat('threshold_review_', ...
                   erase(date,'-'), app.rat);
            uisave(vars, file);
        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            value = app.RSpinner.Value;
            app.r_threshold = value;
            app.update()
        end

        % Value changed function: SWSpinner
        function SWSpinnerValueChanged(app, event)
            value = app.SWSpinner.Value;
            app.sw_threshold = value;
            app.update()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Event')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.LastButton = uibutton(app.GridLayout3, 'push');
            app.LastButton.ButtonPushedFcn = createCallbackFcn(app, @LastButtonPushed, true);
            app.LastButton.Enable = 'off';
            app.LastButton.Text = 'Last';
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.EventsSlider = uislider(app.GridLayout3);
            app.EventsSlider.Limits = [1 100];
            app.EventsSlider.ValueChangedFcn = createCallbackFcn(app, @EventsSliderValueChanged, true);
            app.EventsSlider.Enable = 'off';
            app.EventsSlider.Value = 1;
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.SWThresholdLabel = uilabel(app.GridLayout7);
            app.SWThresholdLabel.Text = 'SW Threshold';
            app.SWSpinner = uispinner(app.GridLayout7);
            app.SWSpinner.ValueChangedFcn = createCallbackFcn(app, @SWSpinnerValueChanged, true);
            app.SWSpinner.Enable = 'off';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.RThresholdLabel = uilabel(app.GridLayout8);
            app.RThresholdLabel.Text = 'R Threshold';
            app.RSpinner = uispinner(app.GridLayout8);
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.RSpinner.Enable = 'off';
            app.SharpwaveCheckBox = uicheckbox(app.GridLayout4);
            app.SharpwaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SharpwaveCheckBoxValueChanged, true);
            app.SharpwaveCheckBox.Enable = 'off';
            app.SharpwaveCheckBox.Text = 'TP SW';
            app.RippleCheckBox = uicheckbox(app.GridLayout4);
            app.RippleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RippleCheckBoxValueChanged, true);
            app.RippleCheckBox.Enable = 'off';
            app.RippleCheckBox.Text = 'TP R';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save thresholds review';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdReviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end